classdef binoxxo_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GespeichertesSpielLadenLabel   matlab.ui.control.Label
        VordefiniertesSpielLadenLabel  matlab.ui.control.Label
        Schwer_8                       matlab.ui.control.Button
        Mittel_8                       matlab.ui.control.Button
        Einfach_8                      matlab.ui.control.Button
        Schwer_6                       matlab.ui.control.Button
        Mittel_6                       matlab.ui.control.Button
        Einfach_6                      matlab.ui.control.Button
        Datei_waehlen                  matlab.ui.control.Button
        x8Label                        matlab.ui.control.Label
        x6Label                        matlab.ui.control.Label
        BinoxxoMenuLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        gridSize % Description
        difficulty % Start Values for the Grid
        fileValues % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Einfach_6
        function SelectEasy_6(app, event)
            app.gridSize = 6;
            app.difficulty = "easy";
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: Mittel_6
        function SelectMedium_6(app, event)
            app.gridSize = 6;
            app.difficulty = "medium";
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: Schwer_6
        function SelectHard_6(app, event)
            app.gridSize = 6;
            app.difficulty = "hard";
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: Datei_waehlen
        function selectSaveFile(app, event)
            [file,path] = uigetfile("*.txt","Select a Saved Game", "./save_files");
            if file
                app.difficulty = "manual";
                try
                    [app.fileValues, app.gridSize] = readSaveFile(strcat(path,file));
                    app.UIFigure.Visible = "off";
                catch ME
                    uialert(app.UIFigure, "Error loading your save file, make sure it's formatted correctly.", "Error loading file")
                    disp("Original error message:")
                    disp(ME.message)
                end
            end
        end

        % Button pushed function: Einfach_8
        function SelectEasy_8(app, event)
            app.gridSize = 8;
            app.difficulty = "easy";
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: Mittel_8
        function SelectMedium_8(app, event)
            app.gridSize = 8;
            app.difficulty = "medium";
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: Schwer_8
        function SelectHard_8(app, event)
            app.gridSize = 8;
            app.difficulty = "hard";
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BinoxxoMenuLabel = uilabel(app.UIFigure);
            app.BinoxxoMenuLabel.Text = 'Binoxxo Menu';
            app.x6Label = uilabel(app.UIFigure);
            app.x6Label.Text = '6x6';
            app.x8Label = uilabel(app.UIFigure);
            app.x8Label.Text = '8x8';
            app.Datei_waehlen = uibutton(app.UIFigure, 'push');
            app.Datei_waehlen.ButtonPushedFcn = createCallbackFcn(app, @selectSaveFile, true);
            app.Datei_waehlen.Text = 'Datei Auswählen';
            app.Einfach_6 = uibutton(app.UIFigure, 'push');
            app.Einfach_6.ButtonPushedFcn = createCallbackFcn(app, @SelectEasy_6, true);
            app.Einfach_6.Text = 'Einfach';
            app.Mittel_6 = uibutton(app.UIFigure, 'push');
            app.Mittel_6.ButtonPushedFcn = createCallbackFcn(app, @SelectMedium_6, true);
            app.Mittel_6.Text = 'Mittel';
            app.Schwer_6 = uibutton(app.UIFigure, 'push');
            app.Schwer_6.ButtonPushedFcn = createCallbackFcn(app, @SelectHard_6, true);
            app.Schwer_6.Text = 'Schwer';
            app.Einfach_8 = uibutton(app.UIFigure, 'push');
            app.Einfach_8.ButtonPushedFcn = createCallbackFcn(app, @SelectEasy_8, true);
            app.Einfach_8.Text = 'Einfach';
            app.Mittel_8 = uibutton(app.UIFigure, 'push');
            app.Mittel_8.ButtonPushedFcn = createCallbackFcn(app, @SelectMedium_8, true);
            app.Mittel_8.Text = 'Mittel';
            app.Schwer_8 = uibutton(app.UIFigure, 'push');
            app.Schwer_8.ButtonPushedFcn = createCallbackFcn(app, @SelectHard_8, true);
            app.Schwer_8.Text = 'Schwer';
            app.VordefiniertesSpielLadenLabel = uilabel(app.UIFigure);
            app.VordefiniertesSpielLadenLabel.Text = 'Vordefiniertes Spiel Laden';
            app.GespeichertesSpielLadenLabel = uilabel(app.UIFigure);
            app.GespeichertesSpielLadenLabel.Text = 'Gespeichertes Spiel Laden';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binoxxo_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end