classdef binoxxo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SideMenu              matlab.ui.container.Panel
        RestartGameButton     matlab.ui.control.Button
        QuitButton            matlab.ui.control.Button
        VerifySolutionButton  matlab.ui.control.Button
        SaveGameButton        matlab.ui.control.Button
        ViewRulesButton       matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        ValueButtons          matlab.ui.container.GridLayout
        Button_36             matlab.ui.control.Button
        Button_35             matlab.ui.control.Button
        Button_34             matlab.ui.control.Button
        Button_33             matlab.ui.control.Button
        Button_32             matlab.ui.control.Button
        Button_31             matlab.ui.control.Button
        Button_30             matlab.ui.control.Button
        Button_29             matlab.ui.control.Button
        Button_28             matlab.ui.control.Button
        Button_27             matlab.ui.control.Button
        Button_26             matlab.ui.control.Button
        Button_25             matlab.ui.control.Button
        Button_24             matlab.ui.control.Button
        Button_23             matlab.ui.control.Button
        Button_22             matlab.ui.control.Button
        Button_21             matlab.ui.control.Button
        Button_20             matlab.ui.control.Button
        Button_19             matlab.ui.control.Button
        Button_18             matlab.ui.control.Button
        Button_17             matlab.ui.control.Button
        Button_16             matlab.ui.control.Button
        Button_15             matlab.ui.control.Button
        Button_14             matlab.ui.control.Button
        Button_13             matlab.ui.control.Button
        Button_12             matlab.ui.control.Button
        Button_11             matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_1              matlab.ui.control.Button
        BinoxxoLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        grid % Description
        rules % Rules window
        initialValues        
    end
    
    properties (Access = public)
        backToMenu % NOT IMPLEMENTED
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initialValues, manualGame)
            app.grid = Grid(6);
            % if initial values are passed to the grid, set the values in
            % the grid class and set the button text. Then disable all
            % buttons with initial values.
            if exist("initialValues", "var")
                app.initialValues = initialValues;
                app.grid.values = app.initialValues;
                for i=1:app.grid.size^2
                    app.ValueButtons.Children(i).Text = app.grid.values(i);
                end
                % If manualGame == true, don't disable the buttons with
                % initial values
                if exist("manualGame", "var")
                    if manualGame == true
                        return
                    end
                end
                disableButtons = app.ValueButtons.Children(app.initialValues ~= "");
                for i=1:length(disableButtons)
                    disableButtons(i).Enable = false;
                end
            end


            %app.backToMenu = false;
            
            % Import Rules UI as property
            app.rules = Rules;
            app.rules.UIFigure.Visible = "off";
        end

        % Button pushed function: Button_1, Button_10, Button_11, 
        % ...and 33 other components
        function nextValue(app, event)
            % Switches the button to the next symbol in options (i.e. from
            % O to X when Binoxxo is selected).
            
            current_index = find(app.grid.options==event.Source.Text);
            id = str2num(event.Source.Tag);
            if(current_index==3);current_index=0;end
            app.grid.values(id) = app.grid.options{current_index+1};
            event.Source.Text = app.grid.values(id);
        end

        % Button pushed function: VerifySolutionButton
        function verifyGrid(app, event)
            app.grid.verifyGrid(app);
        end

        % Button pushed function: ViewRulesButton
        function viewRules(app, event)
            % Tries to set the rules window to visible, if it has been
            % deleted it creates a new rules window before trying to set
            % it to visible
            try
                app.rules.UIFigure.Visible = 'on';
            catch
                app.rules = Rules;
                app.rules.UIFigure.Visible = 'on';
            end
            movegui(app.rules.UIFigure, "center");
        end

        % Close request function: UIFigure
        function cleanupFunc(app, event)
            delete(app.rules)
            app.backToMenu = false;
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: SaveGameButton
        function saveGame(app, event)
            app.grid.saveToFile();
        end

        % Button pushed function: QuitButton
        function quitGame(app, event)
            % Ask before quitting and then close off all windows
            answer = questdlg("Your current progress will be lost. Save the game first if you wish to return later. Do you wish to close the game?", "Attention!", "Yes","No", "No");
            switch answer
                case "Yes"
                    app.UIFigure.Visible = "off";
                    app.rules.delete
                    app.delete
                case "No"
            end
            
        end

        % Button pushed function: RestartGameButton
        function restartGame(app, event)
            % Asks for confirmation and resets the board to the initial
            % values
            answer = questdlg("Your current progress will be lost. Save the game first if you wish to return later. Do you wish to reset the board?", "Attention!", "Yes","No", "No");
            switch answer
                case "Yes"
                    app.grid.values = app.initialValues;
                    for i=1:app.grid.size^2
                        app.ValueButtons.Children(i).Text = app.grid.values(i);
                    end
                case "No"
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Binoxxo Game';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @cleanupFunc, true);
            app.BinoxxoLabel = uilabel(app.UIFigure);
            app.BinoxxoLabel.Text = 'Binoxxo';
            app.Panel = uipanel(app.UIFigure);
            app.ValueButtons = uigridlayout(app.Panel);
            app.Button_1 = uibutton(app.ValueButtons, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_1.Tag = '1';
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.ValueButtons, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_2.Tag = '2';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ValueButtons, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_3.Tag = '3';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.ValueButtons, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_4.Tag = '4';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.ValueButtons, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_5.Tag = '5';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.ValueButtons, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_6.Tag = '6';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.ValueButtons, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_7.Tag = '7';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.ValueButtons, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_8.Tag = '8';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.ValueButtons, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_9.Tag = '9';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.ValueButtons, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_10.Tag = '10';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.ValueButtons, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_11.Tag = '11';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.ValueButtons, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_12.Tag = '12';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.ValueButtons, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_13.Tag = '13';
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.ValueButtons, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_14.Tag = '14';
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.ValueButtons, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_15.Tag = '15';
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.ValueButtons, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_16.Tag = '16';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.ValueButtons, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_17.Tag = '17';
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.ValueButtons, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_18.Tag = '18';
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.ValueButtons, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_19.Tag = '19';
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.ValueButtons, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_20.Tag = '20';
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.ValueButtons, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_21.Tag = '21';
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.ValueButtons, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_22.Tag = '22';
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.ValueButtons, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_23.Tag = '23';
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.ValueButtons, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_24.Tag = '24';
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.ValueButtons, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_25.Tag = '25';
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.ValueButtons, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_26.Tag = '26';
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.ValueButtons, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_27.Tag = '27';
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.ValueButtons, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_28.Tag = '28';
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.ValueButtons, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_29.Tag = '29';
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.ValueButtons, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_30.Tag = '30';
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.ValueButtons, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_31.Tag = '31';
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.ValueButtons, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_32.Tag = '32';
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.ValueButtons, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_33.Tag = '33';
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.ValueButtons, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_34.Tag = '34';
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.ValueButtons, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_35.Tag = '35';
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.ValueButtons, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @nextValue, true);
            app.Button_36.Tag = '36';
            app.Button_36.Text = '';
            app.SideMenu = uipanel(app.UIFigure);
            app.ViewRulesButton = uibutton(app.SideMenu, 'push');
            app.ViewRulesButton.ButtonPushedFcn = createCallbackFcn(app, @viewRules, true);
            app.ViewRulesButton.Text = 'View Rules';
            app.SaveGameButton = uibutton(app.SideMenu, 'push');
            app.SaveGameButton.ButtonPushedFcn = createCallbackFcn(app, @saveGame, true);
            app.SaveGameButton.Text = 'Save Game';
            app.VerifySolutionButton = uibutton(app.SideMenu, 'push');
            app.VerifySolutionButton.ButtonPushedFcn = createCallbackFcn(app, @verifyGrid, true);
            app.VerifySolutionButton.Text = 'Verify Solution';
            app.QuitButton = uibutton(app.SideMenu, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @quitGame, true);
            app.QuitButton.Text = 'Quit';
            app.RestartGameButton = uibutton(app.SideMenu, 'push');
            app.RestartGameButton.ButtonPushedFcn = createCallbackFcn(app, @restartGame, true);
            app.RestartGameButton.Text = 'Restart Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binoxxo_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end